classdef MRIImageProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        OutputImagePanel               matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        OutputImage                    matlab.ui.control.Image
        SourceImagePanel               matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        FilePathField                  matlab.ui.control.Label
        FileNameField                  matlab.ui.control.Label
        FilepathLabel                  matlab.ui.control.Label
        FilenameLabel                  matlab.ui.control.Label
        SourceImage                    matlab.ui.control.Image
        MRIImageProcessorLabel         matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        ImportFileButton               matlab.ui.control.Button
        ParameterAdjustPanel           matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        CLIPButton                     matlab.ui.control.StateButton
        PreserveHighlightsSlider       matlab.ui.control.Slider
        PreserveHighlightsSliderLabel  matlab.ui.control.Label
        VoxelSizeField                 matlab.ui.control.NumericEditField
        VoxelSizemm3SliderLabel        matlab.ui.control.Label
        VoxelSizeSlider                matlab.ui.control.Slider
        B0Field                        matlab.ui.control.NumericEditField
        B0Slider                       matlab.ui.control.Slider
        B0TLabel                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        fullPath = ''; % Initialises variable to store the input file path and name
        outputFilePath = ''; % Initialises variable to store output file path
        
        B0 = 1.5;
        voxelSize = 1.5;

        compensation = 0;

        autoHighlights = 0;
    end
    
    methods (Access = private)
        
        function [] = updateOutput(app) % Function used to process the image, export it and update the output display
            colourImageDataMatrix = imread(app.fullPath); % Import the image and set the imageDataMatrix to the correct values from the source
            imageDataMatrix = rgb2gray(colourImageDataMatrix); % Convert image into greyscale for processing
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                   Image Processing
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % The image contrast can be increased and decreased by scaling 
            % it according to the variables provided (B0 and Voxel Size).
            % The contrast should increase linearly as B0 increases
            % The contrast should decrease linearly as Voxel Size increases
            %
            % Using this information, contrastScaler = (B0 - Voxel Size)
            %
            % The image can then be processed by multiplying the difference
            % in intensity of each pixel from the average intensity by the
            % contrast value to bring the pixel values closer/further
            % from the average value.

            averageIntensity = mean(imageDataMatrix, "all"); % Finding average intensity of input file


            contrastScaler = app.B0 - app.voxelSize; % Calculates the contrast scaler
            if(contrastScaler < -1)
                contrastScaler = -1;
            end

            maxIntensityComp = ((int16(max(imageDataMatrix, [], "all")) - (averageIntensity - int16(max(imageDataMatrix, [], "all")))*contrastScaler) - 255); % Calculates value for absolute highest value after contrast adjustment and subtracts 255 to find the number needed to restore it

            % Handles the preserve highlights auto mode
            if(app.autoHighlights)
                app.compensation = maxIntensityComp;
            end
            
            for i = 1:size(imageDataMatrix,1)
                for j = 1:size(imageDataMatrix,2)
                    if(imageDataMatrix(i,j) < averageIntensity)
                        imageDataMatrix(i,j) = (int16(imageDataMatrix(i,j)) - ((averageIntensity - int16(imageDataMatrix(i,j)))*contrastScaler)) - app.compensation;
                    elseif(imageDataMatrix(i,j) > averageIntensity)
                        imageDataMatrix(i,j) = (int16(imageDataMatrix(i,j)) + ((int16(imageDataMatrix(i,j)) - averageIntensity)*contrastScaler)) - app.compensation;    
                    end
                end
            end

            imwrite(imageDataMatrix, app.outputFilePath); % Write the processed data matrix to the output file
            app.OutputImage.ImageSource = cat(3, imageDataMatrix, imageDataMatrix, imageDataMatrix); % Update the processed image display to reflect the changes (duplicates greyscale data into rgb channels)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: B0Slider
        function B0SliderValueChanged(app, event)
            % Updates value display with value slider is set to
            value = app.B0Slider.Value;
            app.B0 = value;
            updateOutput(app);
            app.B0Field.Value = round(value,2,"decimals");
        end

        % Value changed function: VoxelSizeSlider
        function VoxelSizeSliderValueChanged(app, event)
            % Updates value display with value slider is set to
            value = app.VoxelSizeSlider.Value;
            app.voxelSize = value;
            updateOutput(app);
            app.VoxelSizeField.Value = round(value,2,"decimals");
        end

        % Value changing function: B0Slider
        function B0SliderValueChanging(app, event)
            % Updates value display with value slider is currently at
            changingValue = event.Value;
            app.B0Field.Value = changingValue;
        end

        % Value changing function: VoxelSizeSlider
        function VoxelSizeSliderValueChanging(app, event)
            % Updates value display with value slider is currently at
            changingValue = event.Value;
            app.VoxelSizeField.Value = changingValue;
        end

        % Value changed function: B0Field
        function B0FieldValueChanged(app, event)
            % Updates slider position with the value entered
            value = app.B0Field.Value;
            app.B0 = value;
            updateOutput(app);
            app.B0Slider.Value = value;
        end

        % Value changed function: VoxelSizeField
        function VoxelSizeFieldValueChanged(app, event)
            % Updates slider position with the value entered
            value = app.VoxelSizeField.Value;
            app.voxelSize = value;
            updateOutput(app);
            app.VoxelSizeSlider.Value = value;
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            app.ImportFileButton.Enable = 'off'; % Disables button
            app.SourceImage.Enable = 'off'; % Disables source image display
            app.OutputImage.Enable = 'off'; % Disables output image display
            try % Attempts the following code
                % Importing image file
                app.StatusLabel.Text = "Importing image..."; % Updates the status display
                [image,path] = uigetfile({'*.jpg; *.jpeg; *.JPG; *.JPEG; *.png; *.PNG'},'Select an image'); % Prompts user to select a file for input and stores the filename and path as variables
                app.fullPath = append(path, image); % Converts the filename and path to a single string

                % Updating displays
                app.outputFilePath = append(path, "Processed.png");
                app.FileNameField.Text = image; % Updates file name field
                app.FilePathField.Text = app.fullPath; % Updates file path field
                app.SourceImage.ImageSource = app.fullPath; % Sets the source image display to the image selected
                
                % Enabling components for user interaction
                app.SourceImage.Enable = 'on'; % Enables the image display
                app.ImportFileButton.Enable = 'on'; % Re-enables button
                updateOutput(app); % Updates the output image with the imported image
                app.OutputImage.Enable = 'on'; % Enables the output image display
                app.B0Field.Enable = 'on';
                app.B0Slider.Enable = 'on';
                app.B0TLabel.Enable = 'on';
                app.VoxelSizeField.Enable = 'on';
                app.VoxelSizeSlider.Enable = 'on';
                app.VoxelSizemm3SliderLabel.Enable = 'on';
                app.PreserveHighlightsSlider.Enable = 'on';
                app.PreserveHighlightsSliderLabel.Enable = 'on';
                app.CLIPButton.Enable = 'on';
                % Success message
                app.StatusLabel.Text = " Image ready for processing"; % Updates the status display
            catch % If the code produces an error, the following will execute
                app.StatusLabel.Text = "Import failed, Please try again"; % Updates the status display
                app.ImportFileButton.Enable = 'on'; % Re-enables button
                drawnow; % Forces the app to render the button as enabled
                pause(5); % Waits 5 seconds to allow user to read error message
                app.StatusLabel.Text = "Import a file"; % Updates the status display
            end % Ends the try statement
        end

        % Value changed function: PreserveHighlightsSlider
        function PreserveHighlightsSliderValueChanged(app, event)
            value = app.PreserveHighlightsSlider.Value;
            app.compensation = value;
            updateOutput(app);
        end

        % Value changed function: CLIPButton
        function CLIPButtonValueChanged(app, event)
            value = app.CLIPButton.Value;
            if(value == 1)
                app.autoHighlights = 1;
                app.PreserveHighlightsSlider.Enable = 'off';
                app.updateOutput();
            else
                app.autoHighlights = 0;
                app.PreserveHighlightsSlider.Enable = 'on';
                app.compensation = app.PreserveHighlightsSlider.Value;
                app.updateOutput();
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.ParameterAdjustPanel = uipanel(app.GridLayout2);
            app.ParameterAdjustPanel.Title = 'Parameter Adjust';
            app.GridLayout = uigridlayout(app.ParameterAdjustPanel);
            app.B0TLabel = uilabel(app.GridLayout);
            app.B0TLabel.Enable = 'off';
            app.B0TLabel.Text = 'B0/T';
            app.B0Slider = uislider(app.GridLayout);
            app.B0Slider.Limits = [0.5 11];
            app.B0Slider.MajorTicks = [0.5 3 7 11];
            app.B0Slider.ValueChangedFcn = createCallbackFcn(app, @B0SliderValueChanged, true);
            app.B0Slider.ValueChangingFcn = createCallbackFcn(app, @B0SliderValueChanging, true);
            app.B0Slider.MinorTicks = [];
            app.B0Slider.Enable = 'off';
            app.B0Slider.Value = 1.5;
            app.B0Field = uieditfield(app.GridLayout, 'numeric');
            app.B0Field.Limits = [0.5 11];
            app.B0Field.ValueDisplayFormat = '%2.2g';
            app.B0Field.ValueChangedFcn = createCallbackFcn(app, @B0FieldValueChanged, true);
            app.B0Field.Enable = 'off';
            app.B0Field.Value = 1.5;
            app.VoxelSizeSlider = uislider(app.GridLayout);
            app.VoxelSizeSlider.Limits = [0.5 5];
            app.VoxelSizeSlider.MajorTicks = [0.5 1.5 5];
            app.VoxelSizeSlider.ValueChangedFcn = createCallbackFcn(app, @VoxelSizeSliderValueChanged, true);
            app.VoxelSizeSlider.ValueChangingFcn = createCallbackFcn(app, @VoxelSizeSliderValueChanging, true);
            app.VoxelSizeSlider.MinorTicks = [];
            app.VoxelSizeSlider.Enable = 'off';
            app.VoxelSizeSlider.Value = 1.5;
            app.VoxelSizemm3SliderLabel = uilabel(app.GridLayout);
            app.VoxelSizemm3SliderLabel.Enable = 'off';
            app.VoxelSizemm3SliderLabel.Text = 'Voxel Size /mm^3';
            app.VoxelSizeField = uieditfield(app.GridLayout, 'numeric');
            app.VoxelSizeField.Limits = [0.5 5];
            app.VoxelSizeField.ValueDisplayFormat = '%2.2g';
            app.VoxelSizeField.ValueChangedFcn = createCallbackFcn(app, @VoxelSizeFieldValueChanged, true);
            app.VoxelSizeField.Enable = 'off';
            app.VoxelSizeField.Value = 1.5;
            app.PreserveHighlightsSliderLabel = uilabel(app.GridLayout);
            app.PreserveHighlightsSliderLabel.Enable = 'off';
            app.PreserveHighlightsSliderLabel.Text = 'Preserve Highlights';
            app.PreserveHighlightsSlider = uislider(app.GridLayout);
            app.PreserveHighlightsSlider.Limits = [0 2000];
            app.PreserveHighlightsSlider.ValueChangedFcn = createCallbackFcn(app, @PreserveHighlightsSliderValueChanged, true);
            app.PreserveHighlightsSlider.Enable = 'off';
            app.CLIPButton = uibutton(app.GridLayout, 'state');
            app.CLIPButton.ValueChangedFcn = createCallbackFcn(app, @CLIPButtonValueChanged, true);
            app.CLIPButton.Enable = 'off';
            app.CLIPButton.Text = 'CLIP';
            app.ImportFileButton = uibutton(app.GridLayout2, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.StatusLabel = uilabel(app.GridLayout2);
            app.StatusLabel.Text = 'Import a file';
            app.MRIImageProcessorLabel = uilabel(app.GridLayout2);
            app.MRIImageProcessorLabel.Text = 'MRI Image Processor';
            app.SourceImagePanel = uipanel(app.GridLayout2);
            app.SourceImagePanel.Title = 'Source Image';
            app.GridLayout3 = uigridlayout(app.SourceImagePanel);
            app.SourceImage = uiimage(app.GridLayout3);
            app.SourceImage.Enable = 'off';
            app.FilenameLabel = uilabel(app.GridLayout3);
            app.FilenameLabel.Text = 'File name:';
            app.FilepathLabel = uilabel(app.GridLayout3);
            app.FilepathLabel.Text = 'File path:';
            app.FileNameField = uilabel(app.GridLayout3);
            app.FileNameField.Text = 'N/A';
            app.FilePathField = uilabel(app.GridLayout3);
            app.FilePathField.Text = 'N/A';
            app.OutputImagePanel = uipanel(app.GridLayout2);
            app.OutputImagePanel.Title = 'Output Image';
            app.GridLayout4 = uigridlayout(app.OutputImagePanel);
            app.OutputImage = uiimage(app.GridLayout4);
            app.OutputImage.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRIImageProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end